classdef Final_Re2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        uipanel2          matlab.ui.container.Panel
        AdjustBrightness  matlab.ui.control.Label
        Rotate            matlab.ui.control.Label
        slider4           matlab.ui.control.Slider
        slider3           matlab.ui.control.Slider
        uipanel1          matlab.ui.container.Panel
        popupmenu1        matlab.ui.control.DropDown
        InsertImage       matlab.ui.control.Button
        text2             matlab.ui.control.Label
        text1             matlab.ui.control.Label
        axes2             matlab.ui.control.UIAxes
        axes1             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Final_Re2_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Final_Re2 (see VARARGIN)
            
            % Choose default command line output for Final_Re2
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: InsertImage
        function InsertImageButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            global x;
            [a, b] = uigetfile({'*jpg'; '*jpeg'; '*png'; '*jiff'});
            x = strcat(b, a);
            x = imread(x);
            axes(handles.axes1);
            imshow(x);
        end

        % Value changed function: popupmenu1
        function popupmenu1ValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            global value;
            value = app.popupmenu1.Value;
            disp(value)
            %val = get(handles.popupmenu1, 'value');
            %disp(val);
            global x;
            switch(value)
                case 'Negative Image'
                    global x1;
                    L = 2 ^ 8;
                    x1 = (L - 1) - x;
                    axes(handles.axes2);
                    imshow(x1);
                    handles.x1 = x1;
                case 'RGB to Gray'
                    global x2;
                    x2 = im2gray(x);
                    axes(handles.axes2);
                    imshow(x2);
                    handles.x2 = x2;
                case 'RGB to Black and White'
                    global x3;
                    x3 = im2bw(x);
                    axes(handles.axes2);
                    imshow(x3);
                    handles.x3 = x3;
                case 'Contrast Stretching'
                    global x4;
                    x4 = imadjust(x,stretchlim(x),[]);
                    axes(handles.axes2);
                    imshow(x4);
                    handles.x4 = x4;
                case 'Flip Image'
                    global x5;
                    x5 = flipdim(x,2);
                    axes(handles.axes2);
                    imshow(x5);
                    handles.x5 = x5;
                case 'Sharpen Image'
                    global x6;
                    x6 = imsharpen(x,'Radius',5.5);
                    axes(handles.axes2);
                    imshow(x6);
                    handles.x6 = x6;
                case 'Noise Image'
                    global x7;
                    x7 = imnoise(x, "gaussian", 0, 0.08);
                    axes(handles.axes2);
                    imshow(x7);
                    handles.x7 = x7;
            end
        end

        % Value changed function: slider3
        function slider3ValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            global value;
            switch(value)
                case 'Negative Image'
                    global x1;
                    val = app.slider3.Value;
                    New_img = imrotate(x1, val);
                    axes(handles.axes2);
                    imshow(New_img);
                case 'RGB to Gray'
                    global x2;
                    val = app.slider3.Value;
                    New_img = imrotate(x2, val);
                    axes(handles.axes2);
                    imshow(New_img);
                case 'RGB to Black and White'
                    global x3;
                    val = app.slider3.Value;
                    New_img = imrotate(x3, val);
                    axes(handles.axes2);
                    imshow(New_img);
                case 'Contrast Stretching'
                    global x4;
                    val = app.slider3.Value;
                    New_img = imrotate(x4, val);
                    axes(handles.axes2);
                    imshow(New_img);
                case 'Flip Image'
                    global x5;
                    val = app.slider3.Value;
                    New_img = imrotate(x5, val);
                    axes(handles.axes2);
                    imshow(New_img);
                case 'Sharpen Image'
                    global x6;
                    val = app.slider3.Value;
                    New_img = imrotate(x6, val);
                    axes(handles.axes2);
                    imshow(New_img);
                case 'Noise Image'
                    global x7;
                    val = app.slider3.Value;
                    New_img = imrotate(x7, val);
                    axes(handles.axes2);
                    imshow(New_img);
            end
        end

        % Value changed function: slider4
        function slider4ValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            global value;
            switch(value)
                case 'Negative Image'
                    global x1;
                    val = app.slider4.Value;
                    New_img = x1 + val;
                    axes(handles.axes2);
                    imshow(New_img);
                case 'RGB to Gray'
                    global x2;
                    val = app.slider4.Value;
                    New_img = x2 + val;
                    axes(handles.axes2);
                    imshow(New_img);
                case 'RGB to Black and White'
                    global x3;
                    val = app.slider4.Value;
                    New_img = x3 + val;
                    axes(handles.axes2);
                    imshow(New_img);
                case 'Contrast Stretching'
                    global x4;
                    val = app.slider4.Value;
                    New_img = x4 + val;
                    axes(handles.axes2);
                    imshow(New_img);
                case 'Flip Image'
                    global x5;
                    val = app.slider4.Value;
                    New_img = x5 + val;
                    axes(handles.axes2);
                    imshow(New_img);
                case 'Sharpen Image'
                    global x6;
                    val = app.slider4.Value;
                    New_img = x6 + val;
                    axes(handles.axes2);
                    imshow(New_img);
                case 'Noise Image'
                    global x7;
                    val = app.slider4.Value;
                    New_img = x7 + val;
                    axes(handles.axes2);
                    imshow(New_img);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Final_Re2';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Original Image';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Processed Image';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Option';
            app.uipanel1.Tag = 'uipanel1';
            app.InsertImage = uibutton(app.uipanel1, 'push');
            app.InsertImage.ButtonPushedFcn = createCallbackFcn(app, @InsertImageButtonPushed, true);
            app.InsertImage.Tag = 'InsertImage';
            app.InsertImage.Text = 'Insert Image';
            app.popupmenu1 = uidropdown(app.uipanel1);
            app.popupmenu1.Items = {'Select Process', 'Negative Image', 'RGB to Gray', 'RGB to Black and White', 'Contrast Stretching', 'Flip Image', 'Sharpen Image', 'Noise Image'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1ValueChanged, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Select Process';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Processed Image Panel';
            app.uipanel2.Tag = 'uipanel2';
            app.slider3 = uislider(app.uipanel2);
            app.slider3.Limits = [0 360];
            app.slider3.MajorTicks = [];
            app.slider3.ValueChangedFcn = createCallbackFcn(app, @slider3ValueChanged, true);
            app.slider3.MinorTicks = [];
            app.slider3.Tag = 'slider3';
            app.slider4 = uislider(app.uipanel2);
            app.slider4.Limits = [0 200];
            app.slider4.MajorTicks = [];
            app.slider4.ValueChangedFcn = createCallbackFcn(app, @slider4ValueChanged, true);
            app.slider4.MinorTicks = [];
            app.slider4.Tag = 'slider4';
            app.Rotate = uilabel(app.uipanel2);
            app.Rotate.Tag = 'Rotate';
            app.Rotate.Text = 'Rotate';
            app.AdjustBrightness = uilabel(app.uipanel2);
            app.AdjustBrightness.Tag = 'AdjustBrightness';
            app.AdjustBrightness.Text = 'Adjust Brightness';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Re2_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Final_Re2_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end